classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LoadButton        matlab.ui.control.Button
        FBPButton         matlab.ui.control.Button
        ButtonGroup       matlab.ui.container.ButtonGroup
        SheppLoganButton  matlab.ui.control.RadioButton
        Button            matlab.ui.control.RadioButton
        ButtonGroup_2     matlab.ui.container.ButtonGroup
        Button_2          matlab.ui.control.RadioButton
        Button_3          matlab.ui.control.RadioButton
        TextArea          matlab.ui.control.TextArea
        CheckBox          matlab.ui.control.CheckBox
        Label_7           matlab.ui.control.Label
        Label_1           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        Label_10          matlab.ui.control.Label
        Label_11          matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
    end

   %丁昊妍 520021910379 
    properties (Access = public)
        P_choose0 = 1;
        P_choose = 1 % 图像选择
        radonway_choose = 1; %投影方式选择
        filter_choose = 1; %滤波选择
    end
    
    
    methods (Access = public)

        function updateGUI(app, i)  % Create an object that gets the run-time value of the specified block     
            app.Label_7.Text = i;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.P_choose0 == 1
                M=256;
                [P,E] = phantom('Modified Shepp-Logan', M); % 创建一个 shepp-logan 模型，原图像
                imshow(P,'Parent',app.UIAxes);
                app.P_choose = 1; 
                if app.radonway_choose == 1
                    app.LoadButton.UserData = E;
                else
                    app.LoadButton.UserData = P;
                end         
            else
                app.P_choose = 2;
                [filename,filepath] = uigetfile({'*.png;*.jpg'}, 'Select File to Open');
                fullname = [filepath, filename];
                if fullname ~= 0                    %#ok<BDSCI> 
                    P = imread(fullname);
                    P = imresize(P, [256,256]);
                    P = rgb2gray(P);
                    P = im2double(P); %转换成double
                    imshow(P,'Parent',app.UIAxes);
                    app.LoadButton.UserData = P; 
                end
            end
        end

        % Button pushed function: FBPButton
        function FBPButtonPushed(app, event)
            if app.radonway_choose == 1
                if app.P_choose == 1
                    run("GUIRadon_Computerized.m")
                else 
                    run("GUIRadon.m")
                end
            else
                run("GUIRadon_fan.m")
            end   
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            %选择模型或本地图像
            if selectedButton.Text == "Shepp-Logan模型"
                app.P_choose0 = 1;
            else
                app.P_choose0 = 2;
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            %选择平行束投影或扇形束投影
            if selectedButton.Text == "平行束"
                app.radonway_choose = 1;
            else
                app.radonway_choose = 2;
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            %是否选择滤波器，value=1是，=0否
            if value == 1
                app.filter_choose = 1;
            else
                app.filter_choose = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '显示/选择';
            app.FBPButton = uibutton(app.UIFigure, 'push');
            app.FBPButton.ButtonPushedFcn = createCallbackFcn(app, @FBPButtonPushed, true);
            app.FBPButton.Text = '开始重建';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SheppLoganButton = uiradiobutton(app.ButtonGroup);
            app.SheppLoganButton.Text = 'Shepp-Logan模型';
            app.SheppLoganButton.Value = true;
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '从本地选择图像';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '投影方法';
            app.Button_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_2.Text = '平行束';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup_2);
            app.Button_3.Text = '扇形束';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'注意事项：'; '1、更改了图像选择一定要先显示图像再开始重建，否则图像数据不会更新！'; '2、扇形束投影的重建大致需要1分钟，需耐心等待。'; '3、开始重建后尽量不要点击其他按钮，否则可能影响结果。'; ''; '使用指南：'; '1、可以选择Shepp-Logan模型或从本地选择图像（支持*.png,*.jpg格式）'; '2、选择完后要点击“显示/选择”按钮将图像显示出来。注意导入的图像为了方便和重建图像对比，会自动转为黑白显示，并且考虑到重建速度和显示美观，对所有图片的大小都进行了规格统一，一致自动更改为256×256的大小。'; '2、投影方法可以选择平行束投影或扇形束投影。'; '3、勾选“使用滤波器”，会展示滤波后的投影，并在滤波后反投影。取消勾选，展示直接反投影的重建图像。'; '4、点击“开始重建”会进行投影展示和重建展示。'; '5、会实时显示扫描进度和重建进度。当扫描进度到达100%会显示投影图像，当重建进度到达100%会显示重建图像。'};
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '使用滤波器';
            app.CheckBox.Value = true;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '扫描进度：';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = '原图';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '投影';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '重建图像';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '滤波后的投影';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = {'制作人：丁昊妍'; '学号：520021910379'};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XAxisLocation = 'origin';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YAxisLocation = 'origin';
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.UIAxes4.XAxisLocation = 'origin';
            app.UIAxes4.XTick = [];
            app.UIAxes4.YAxisLocation = 'origin';
            app.UIAxes4.YTick = [];
            app.UIAxes4.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end